classdef ROIAnalysisGUI_github < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearButton                     matlab.ui.control.Button
        Step3ROIanalysisPanel           matlab.ui.container.Panel
        SelectedROIprofileLabel         matlab.ui.control.Label
        MeanMBcountinvesselsEditField   matlab.ui.control.NumericEditField
        MeanMBcountinvesselsEditFieldLabel  matlab.ui.control.Label
        VesselAreapixelsEditField       matlab.ui.control.NumericEditField
        VesselAreapixelsEditFieldLabel  matlab.ui.control.Label
        TotalMBcountEditField           matlab.ui.control.NumericEditField
        TotalMBcountEditFieldLabel      matlab.ui.control.Label
        InputROInameEditField           matlab.ui.control.EditField
        InputROInameEditFieldLabel      matlab.ui.control.Label
        confirmandanalyzeButton         matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        ROISelectionsavedLamp           matlab.ui.control.Lamp
        ROISelectionsavedLampLabel      matlab.ui.control.Label
        Step2ROIselectionPanel          matlab.ui.container.Panel
        LateralEditField                matlab.ui.control.NumericEditField
        LateralEditFieldLabel           matlab.ui.control.Label
        AxialEditField                  matlab.ui.control.NumericEditField
        AxialEditFieldLabel             matlab.ui.control.Label
        AxialEditFieldLabel_2           matlab.ui.control.Label
        AtlasRegisteredLamp             matlab.ui.control.Lamp
        AtlasRegisteredLampLabel        matlab.ui.control.Label
        ROIShiftAmountEditField         matlab.ui.control.NumericEditField
        ROIShiftAmountEditFieldLabel    matlab.ui.control.Label
        ROIconfirmedLamp                matlab.ui.control.Lamp
        ROIconfirmedLampLabel           matlab.ui.control.Label
        Methods3CopyexistingoneLabel    matlab.ui.control.Label
        Methods2LoadfromAtlasLabel      matlab.ui.control.Label
        Methods1ManuallyDrawingLabel    matlab.ui.control.Label
        ROIadjustmentandconfirmationLabel  matlab.ui.control.Label
        ROIselectoinLabel               matlab.ui.control.Label
        LeftButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        SelectROIfromAtlasButton        matlab.ui.control.Button
        AutoregisterwhencopyingCheckBox  matlab.ui.control.CheckBox
        RegistertoAtlasButton           matlab.ui.control.Button
        CopyROIfromReferenceButton      matlab.ui.control.Button
        SelectROIByDrawingButton        matlab.ui.control.Button
        ConfirmROIButton                matlab.ui.control.Button
        UIAxes_ROISelection             matlab.ui.control.UIAxes
        Step1SelectthedatatoprocessPanel  matlab.ui.container.Panel
        MapsrecognizedListBox           matlab.ui.control.ListBox
        MapsrecognizedListBoxLabel      matlab.ui.control.Label
        DataNameEditField               matlab.ui.control.EditField
        DataNameEditFieldLabel          matlab.ui.control.Label
        MouseIDEditField                matlab.ui.control.EditField
        MouseIDEditFieldLabel           matlab.ui.control.Label
        DataloadedLamp                  matlab.ui.control.Lamp
        DataloadedLampLabel             matlab.ui.control.Label
        LoadedfilenameEditField_2       matlab.ui.control.EditField
        LoadedfilenameEditField_2Label  matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        ULMQuantitativeAnalysisROILabel  matlab.ui.control.Label
        Step2forMethod3onlyLoadareferenceROIPanel  matlab.ui.container.Panel
        REFMap1NonZeroMeanEditField     matlab.ui.control.NumericEditField
        MeanVolocityLabel               matlab.ui.control.Label
        REFMap1BinarySumEditField       matlab.ui.control.NumericEditField
        VeseelAreaLabel                 matlab.ui.control.Label
        REFMap1ValueSumEditField        matlab.ui.control.NumericEditField
        MBCountLabel                    matlab.ui.control.Label
        ReferenceROIprofileLabel        matlab.ui.control.Label
        LoadedfilenameEditField         matlab.ui.control.EditField
        LoadedfilenameEditFieldLabel    matlab.ui.control.Label
        LoadReferenceButton             matlab.ui.control.Button
        UIAxes_Ref                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileInfo 
        ULMaccum_result
        ULMdata 
        ROI
        referenceROI
        AllenBrainAtlas
    end
    
    methods (Access = private)
        
        function calculateOvarlayImg(app,mask,ULMmap,axestoPlot)
            util.ImageProcessor.displayOverlay(mask, ULMmap, axestoPlot);
        end

        function resetApp(app)

            if ~isempty(app.ULMaccum_result)
                choice = questdlg('What do you want to clear?', ...
                    'Reset Confirmation', ...
                    'Clear all', 'Clear data only', 'Cancel', 'Cancel');
                
                switch choice
                    case 'Clear all'
                        clearMain = true; clearRef = true;
                    case 'Clear data only'
                        clearMain = true; clearRef = false;
                    otherwise
                        clearMain = false;clearRef = false;
                end

                if clearMain
                    app.fileInfo = [];
                    app.ULMaccum_result = [];
                    app.ULMdata = [];
                    app.ROI = [];
            
                    cla(app.UIAxes_ROISelection);
                    set(app.UIAxes_ROISelection, 'Color', 'white');
            
                    app.LoadedfilenameEditField_2.Value = '';
                    app.MouseIDEditField.Value = '';
                    app.DataNameEditField.Value = '';
                    app.MapsrecognizedListBox.Items = {'to be updated'};
                    app.MapsrecognizedListBox.Value = 'to be updated';
                    app.InputROInameEditField.Value = 'Name the ROI here';
            
                    app.TotalMBcountEditField.Value = 0;
                    app.VesselAreapixelsEditField.Value = 0;
                    app.MeanMBcountinvesselsEditField.Value = 0;
            
                    gray = [0.8 0.8 0.8];
                    app.DataloadedLamp.Color = gray;
                    app.ROIconfirmedLamp.Color = gray;
                    app.ROISelectionsavedLamp.Color = gray;
                    app.AtlasRegisteredLamp.Color = gray;
                end
     
                if clearRef
                    app.referenceROI = [];
                    cla(app.UIAxes_Ref);
                    set(app.UIAxes_Ref, 'Color', 'white');
                    app.REFMap1ValueSumEditField.Value = 0;
                    app.REFMap1BinarySumEditField.Value = 0;
                    app.REFMap1NonZeroMeanEditField.Value = 0;
                    app.LoadedfilenameEditField.Value = '';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('functions')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.DataloadedLamp.Color = [1, 0.5, 0]; 
            drawnow;

            resetApp(app);

            filePath = app.LoadedfilenameEditField_2.Value;
            [app.ULMaccum_result, fileDetails] = util.FileManager.loadULMData(filePath);
            
            if ~isempty(app.ULMaccum_result)
                app.LoadedfilenameEditField_2.Value = fileDetails.fileName;
                app.fileInfo = fileDetails;
                
                 % Prompt user to input MouseID
                mouseIDAnswer = inputdlg('Please enter Mouse ID:', 'Mouse ID Input', [1 40]);
                if ~isempty(mouseIDAnswer)
                    app.fileInfo.MouseID = mouseIDAnswer{1};
                    app.MouseIDEditField.Value = app.fileInfo.MouseID;
                else
                    app.MouseIDEditField.Value = '';
                    app.fileInfo.MouseID = '';
                end


                % Prompt user to input DataName
                dataNameAnswer = inputdlg('Please enter Data Name:', 'Data Name Input', [1 40]);
                if ~isempty(dataNameAnswer)
                    app.fileInfo.DataName = dataNameAnswer{1};
                    app.DataNameEditField.Value = app.fileInfo.DataName;
                else
                    app.DataNameEditField.Value = '';
                    app.fileInfo.DataName = '';
                end

                % Check if required information is provided
                if isempty(app.fileInfo.MouseID) || isempty(app.fileInfo.DataName)
                    uiconfirm(app.UIFigure, 'Please enter MouseID and DataName', 'Missing Information', ...
                              'Options', {'OK'}, ...
                              'DefaultOption', 1, 'CancelOption', 1);
                    return;
                end

                app.ULMdata = app.ULMaccum_result.ULM_final;
                if ~isempty(app.ULMaccum_result)
                    ULMmap = app.ULMdata.mapCounter;
                    imagesc(sqrt(ULMmap),'Parent', app.UIAxes_ROISelection);
                    colormap(app.UIAxes_ROISelection, "gray");  
                    axis(app.UIAxes_ROISelection, 'image');  
                    clim(app.UIAxes_ROISelection, [0, 8]);
    
                    % Get field names from ULMdata and display them in the MaprecognizedListBox
                    fieldNames = fieldnames(app.ULMdata);
                    app.MapsrecognizedListBox.Items = fieldNames;
                    
                    % Select the first item by default if available
                    if ~isempty(fieldNames)
                        app.MapsrecognizedListBox.Value = fieldNames{1};
                    end
                end
                app.DataloadedLamp.Color = 'green';
            else
                app.DataloadedLamp.Color = 'red';
            end
        end

        % Button pushed function: SelectROIByDrawingButton
        function SelectROIByDrawingButtonPushed(app, event)
            [app.ROI.mask, app.ROI.vertices] = SelectROI_GUI(app.ULMdata.mapCounter);
            app.calculateOvarlayImg(app.ROI.mask,app.ULMdata.mapCounter,app.UIAxes_ROISelection);
            app.ROISelectionsavedLamp.Color = 'red'; 
            app.ROIconfirmedLamp.Color = 'red';
        end

        % Button pushed function: ConfirmROIButton
        function ConfirmROIButtonPushed(app, event)
            app.ROIconfirmedLamp.Color = [1, 0.5, 0]; 
            drawnow;

            % Check again if required information is provided
            if isempty(app.fileInfo.MouseID) || isempty(app.fileInfo.DataName)
                uiconfirm(app.UIFigure, 'Please input MouseID and DataName in the main panel and click confirm', 'Missing Information', ...
                          'Options', {'OK'}, ...
                          'DefaultOption', 1, 'CancelOption', 1);
                app.ROIconfirmedLamp.Color = 'red';
                return;
            end

            app.ROI.ROISelection = util.ROISelection();
            app.ROI.ROISelection.mask = app.ROI.mask;
            app.ROI.ROISelection.ULMmap = app.ULMdata.mapCounter;
            app.ROI.ROISelection.fileName = app.fileInfo.fileName;
            app.ROI.ROISelection.dirName = app.fileInfo.filePath;
            app.ROI.ROISelection.MouseID = app.fileInfo.MouseID;
            app.ROI.ROISelection.DataName = app.fileInfo.DataName;

            if ~isempty(app.ROI.ROISelection)
                app.ROIconfirmedLamp.Color = 'green'; 
                app.ROISelectionsavedLamp.Color = 'red'; 
            else
                app.ROIconfirmedLamp.Color = 'red';
            end
        end

        % Button pushed function: confirmandanalyzeButton
        function confirmandanalyzeButtonPushed(app, event)
            if isequal(app.ROIconfirmedLamp.Color, [0, 1, 0])
                % Set ROI name
                app.ROI.ROISelection.ROIName = app.InputROInameEditField.Value;
                
                % Prepare data for analysis
                mask = logical(app.ROI.ROISelection.mask);

                % Use ROIAnalyzer to analyze ROI with the flexible interface
                % Create a dynamic function call to analyzeROI
                selectedMaps = app.MapsrecognizedListBox.Items;
                args = {};
                
                % First parameter is mask
                args{1} = mask;
                
                % Add the rest of parameters: even positions (2,4,6...) are names, odd positions (3,5,7...) are values
                for i = 1:length(selectedMaps)
                    mapName = selectedMaps{i};
                    paramPosition = i * 2;  % This gives 2,4,6,8...
                    args{paramPosition} = mapName;  % Even positions are parameter names
                    args{paramPosition + 1} = app.ULMdata.(mapName);  % Odd positions are values
                end
                
                % Call the analyzeROI function with the dynamically created arguments
                results = util.ROIAnalyzer.analyzeROI(args{:});
                
                % Add all analysis results to ROISelection object using the flexible interface
                app.ROI.ROISelection.addAnalysisResults(results);
                
                % Update UI with results
                app.TotalMBcountEditField.Value = eval(['results.',selectedMaps{1},'__sum']);
                app.VesselAreapixelsEditField.Value = eval(['results.',selectedMaps{1},'__effective_pixels']);
                app.MeanMBcountinvesselsEditField.Value = eval(['results.',selectedMaps{1},'__mean']);

            else
                uiconfirm(app.UIFigure, 'Warning: ROI not confirmed yet', 'Confirm ROI', ...
                          'Options', {'Okay I will confirm'}, ...
                          'DefaultOption', 1, 'CancelOption', 1);
            end
            app.ROISelectionsavedLamp.Color = [1, 0.5, 0]; 
            drawnow;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.ROISelectionsavedLamp.Color = [1, 0.5, 0]; 
            drawnow;

            savePath = util.FileManager.saveROISelection(app.ROI.ROISelection);
         
            if ~isempty(savePath)
                app.ROISelectionsavedLamp.Color = 'green';
            else
                app.ROISelectionsavedLamp.Color = 'red';
            end
        end

        % Button pushed function: LoadReferenceButton
        function LoadReferenceButtonPushed(app, event)
            refPath = app.LoadedfilenameEditField.Value;
            [refData, refDetails] = util.FileManager.loadULMData(refPath);
            selectedMaps = app.MapsrecognizedListBox.Items;
            
            if ~isempty(refData)
                app.referenceROI = refData;
                app.LoadedfilenameEditField.Value = refDetails.fileName;

                % Update reference ROI information in UI
                app.REFMap1ValueSumEditField.Value = eval(['app.referenceROI.ROISelection.',selectedMaps{1},'__sum']);
                app.REFMap1BinarySumEditField.Value = eval(['app.referenceROI.ROISelection.',selectedMaps{1},'__effective_pixels']);
                app.REFMap1NonZeroMeanEditField.Value = eval(['app.referenceROI.ROISelection.',selectedMaps{1},'__mean']);
                
                mask = app.referenceROI.ROISelection.mask;
                countermap = app.referenceROI.ROISelection.ULMmap;
                app.calculateOvarlayImg(mask, countermap, app.UIAxes_Ref);
            end
        end

        % Button pushed function: CopyROIfromReferenceButton
        function CopyROIfromReferenceButtonPushed(app, event)
            % Calculate normalized cross correlation between reference ULM map and current ULM map
            if app.AutoregisterwhencopyingCheckBox.Value
                disp('auto Register')
                % to determine the displacement between them
                corr_matrix = normxcorr2(app.referenceROI.ROISelection.ULMmap.^0.3, app.ULMdata.mapCounter.^0.3);
        
                % Find the peak correlation and its position
                [max_c, max_idx] = max(corr_matrix(:));
                [y_peak, x_peak] = ind2sub(size(corr_matrix), max_idx);
                
                % Calculate the displacement
                y_shift = y_peak - size(app.referenceROI.ROISelection.ULMmap, 1);
                x_shift = x_peak - size(app.referenceROI.ROISelection.ULMmap, 2);
                
                 % Apply the displacement to the ROI mask using circshift
                app.ROI.mask = circshift(app.referenceROI.ROISelection.mask, [y_shift, x_shift]);
                 % Display the calculated shift for user information
                disp(['Applied ROI shift: x = ', num2str(x_shift), ', y = ', num2str(y_shift)]);
                
            else
                app.ROI.mask = app.referenceROI.ROISelection.mask;
            end
            % Update the overlay display
            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROISelectionsavedLamp.Color = 'red'; 
            app.ROIconfirmedLamp.Color = 'red';
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            shift_amount = app.ROIShiftAmountEditField.Value; 
            app.ROI.mask = util.ROIAnalyzer.shiftROI(app.ROI.mask, shift_amount, 'up');
            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROIconfirmedLamp.Color = 'red';
            app.ROISelectionsavedLamp.Color = 'red'; 
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            shift_amount = app.ROIShiftAmountEditField.Value; 
            app.ROI.mask = util.ROIAnalyzer.shiftROI(app.ROI.mask, shift_amount, 'down');
            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROIconfirmedLamp.Color = 'red';
            app.ROISelectionsavedLamp.Color = 'red'; 
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            shift_amount = app.ROIShiftAmountEditField.Value; 
            app.ROI.mask = util.ROIAnalyzer.shiftROI(app.ROI.mask, shift_amount, 'left');
            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROIconfirmedLamp.Color = 'red';
            app.ROISelectionsavedLamp.Color = 'red'; 
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            shift_amount = app.ROIShiftAmountEditField.Value; 
            app.ROI.mask = util.ROIAnalyzer.shiftROI(app.ROI.mask, shift_amount, 'right');
            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROIconfirmedLamp.Color = 'red';
            app.ROISelectionsavedLamp.Color = 'red'; 
        end

        % Button pushed function: RegistertoAtlasButton
        function RegistertoAtlasButtonPushed(app, event)
            
            choice = questdlg('Please confirm the input pixel sizes are correct.', ...
                    'Pixel Size Confirmation', ...
                    'Yes, they are correct', 'No, I will change them', 'No, I will change them');
                
            if ~strcmp(choice,'Yes, they are correct')
                return
            end
            
            app.AtlasRegisteredLamp.Color = [1, 0.5, 0]; 
            drawnow;

            app.AllenBrainAtlas = load("allen_brain_atlas.mat",'atlas');
            SelectedSlice = Atlas_coronal_plane_select(app.AllenBrainAtlas.atlas);
            
            close all

            atlas = app.AllenBrainAtlas.atlas;
            atlasData = squeeze(atlas.Regions(:, SelectedSlice, :));
          
            colorComp.cmap = atlas.infoRegions.rgb;
            [nx, ny] = size(atlasData);
            cData = double(atlasData(:));
            cData(cData == 0) = 1;
            cData = colorComp.cmap(abs(cData), :);
            cData = reshape(cData, nx, ny, 3);

            app.ULMdata.mapCounter;
            imgData = sqrt(app.ULMdata.mapCounter);

            [shiftedcData, shiftedAtlasData, atlasTransform, confirmed] = ULMcoronalPlaneRegistrationGUI(cData, imgData, app.LateralEditField.Value/1e6, app.AxialEditField.Value/1e6, atlasData);
            if confirmed

                app.ROI.PlaneRegis.SelectedAtlasNumber = SelectedSlice;
                app.ROI.PlaneRegis.RegisteredMask = shiftedAtlasData;
                app.ROI.PlaneRegis.RawMask = atlasData;
                app.ROI.PlaneRegis.RegisteredAtlascData = shiftedcData;
                app.ROI.PlaneRegis.RegisteredTransform = atlasTransform;
    
                save_folder = [app.fileInfo.filePath,'registrationResult\'];
                if not(exist(save_folder,'dir'))
                    mkdir(save_folder)
                end
     
                currentDateTime = datetime;
                currentDateTime.Format = 'yyyyMMddHHmmss';
                numericString = char(currentDateTime);
    
                filename = sprintf('Regist_Altas_%d_', SelectedSlice);
                filepath = fullfile(save_folder, [filename,numericString,'.mat']);
                save(filepath,'imgData','atlasData','shiftedAtlasData', 'shiftedcData','atlasTransform')
    
                app.AtlasRegisteredLamp.Color = 'green'; 
                drawnow;
            else
                app.AtlasRegisteredLamp.Color = 'red'; 
            end
        end

        % Button pushed function: SelectROIfromAtlasButton
        function SelectROIfromAtlasButtonPushed(app, event)
            if ~isequal(app.AtlasRegisteredLamp.Color, [0, 1, 0])
                uiconfirm(app.UIFigure, 'Warning: Atlas not registered yet', 'Register the atlas', ...
                          'Options', {'Okay I will register'}, ...
                          'DefaultOption', 1, 'CancelOption', 1);
                return;
            end

            SelectedSlice = app.ROI.PlaneRegis.SelectedAtlasNumber;
            atlas = app.AllenBrainAtlas.atlas;
            [region_idx, region_condition] = BrainRegionSelection(atlas,SelectedSlice);
            
            % Check if a region was selected
            if isempty(region_idx)
                return;
            end
        
            atlasData = app.ROI.PlaneRegis.RawMask;
            atlasTransform = app.ROI.PlaneRegis.RegisteredTransform;
            
            % Create a mask based on the selected region index
            originalMask = false(size(atlasData));
            % Add each selected region to the mask
            for i = 1:length(region_idx)
               originalMask = originalMask | (atlasData == region_idx(i));
            end
            finalMask = [];
            
            % Get dimensions of the atlas data
            [~, width] = size(originalMask);
            
            % Define the midpoint for left/right separation
            midpoint = round(width / 2);
        
            switch region_condition
                case 'both'
                    % Use the entire mask
                    finalMask = originalMask;
                    app.ROI.SelectedRegionCondition = 'both';
                case 'left' 
                    % Create a mask for the left half only
                    leftMask = false(size(originalMask));
                    leftMask(:, 1:midpoint) = originalMask(:, 1:midpoint);
                    finalMask = leftMask;
                    app.ROI.SelectedRegionCondition = 'left';
                case 'right'
                    % Create a mask for the right half only
                    rightMask = false(size(originalMask));
                    rightMask(:, midpoint+1:end) = originalMask(:, midpoint+1:end);
                    finalMask = rightMask;
                    app.ROI.SelectedRegionCondition = 'right';
                otherwise
                    % No valid condition selected, return without doing anything
                    return;
            end
            
            % Apply the transformation to the mask using the applyAtlasTransform function
            if ~isempty(atlasTransform) && ~isempty(finalMask)
                % Get the target size from shiftedAtlasData
                targetSize = size(app.ULMdata.mapCounter);
                
                % Apply the atlas transformation to the mask
                transformedMask = applyAtlasTransform(finalMask, atlasTransform, targetSize, app.LateralEditField.Value/1e6, app.AxialEditField.Value/1e6);
                app.ROI.mask = transformedMask;
            else
                app.ROI.mask = finalMask;
            end

            app.calculateOvarlayImg(app.ROI.mask, app.ULMdata.mapCounter, app.UIAxes_ROISelection);
            app.ROISelectionsavedLamp.Color = 'red'; 
            app.ROIconfirmedLamp.Color = 'red';

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            resetApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step2forMethod3onlyLoadareferenceROIPanel = uipanel(app.UIFigure);
            app.Step2forMethod3onlyLoadareferenceROIPanel.Title = 'Step 2 (for Method 3 only) : Load a reference ROI';
            app.UIAxes_Ref = uiaxes(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.UIAxes_Ref.XTick = [];
            app.UIAxes_Ref.YTick = [];
            app.LoadReferenceButton = uibutton(app.Step2forMethod3onlyLoadareferenceROIPanel, 'push');
            app.LoadReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceButtonPushed, true);
            app.LoadReferenceButton.Text = 'Load Reference';
            app.LoadedfilenameEditFieldLabel = uilabel(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.LoadedfilenameEditFieldLabel.Text = 'Loaded file name:';
            app.LoadedfilenameEditField = uieditfield(app.Step2forMethod3onlyLoadareferenceROIPanel, 'text');
            app.LoadedfilenameEditField.Editable = 'off';
            app.ReferenceROIprofileLabel = uilabel(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.ReferenceROIprofileLabel.Text = 'Reference ROI profile';
            app.MBCountLabel = uilabel(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.MBCountLabel.Text = 'Total MB count';
            app.REFMap1ValueSumEditField = uieditfield(app.Step2forMethod3onlyLoadareferenceROIPanel, 'numeric');
            app.VeseelAreaLabel = uilabel(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.VeseelAreaLabel.Text = 'Vessel Area (pixels)';
            app.REFMap1BinarySumEditField = uieditfield(app.Step2forMethod3onlyLoadareferenceROIPanel, 'numeric');
            app.MeanVolocityLabel = uilabel(app.Step2forMethod3onlyLoadareferenceROIPanel);
            app.MeanVolocityLabel.Text = 'Mean MB count in vessels';
            app.REFMap1NonZeroMeanEditField = uieditfield(app.Step2forMethod3onlyLoadareferenceROIPanel, 'numeric');
            app.ULMQuantitativeAnalysisROILabel = uilabel(app.UIFigure);
            app.ULMQuantitativeAnalysisROILabel.Text = 'ULM Quantitative Analysis - ROI';
            app.Step1SelectthedatatoprocessPanel = uipanel(app.UIFigure);
            app.Step1SelectthedatatoprocessPanel.Title = 'Step 1: Select the data to process';
            app.LoadDataButton = uibutton(app.Step1SelectthedatatoprocessPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadedfilenameEditField_2Label = uilabel(app.Step1SelectthedatatoprocessPanel);
            app.LoadedfilenameEditField_2Label.Text = 'Loaded file name';
            app.LoadedfilenameEditField_2 = uieditfield(app.Step1SelectthedatatoprocessPanel, 'text');
            app.LoadedfilenameEditField_2.Editable = 'off';
            app.DataloadedLampLabel = uilabel(app.Step1SelectthedatatoprocessPanel);
            app.DataloadedLampLabel.Text = 'Data loaded';
            app.DataloadedLamp = uilamp(app.Step1SelectthedatatoprocessPanel);
            app.MouseIDEditFieldLabel = uilabel(app.Step1SelectthedatatoprocessPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.Step1SelectthedatatoprocessPanel, 'text');
            app.MouseIDEditField.Editable = 'off';
            app.DataNameEditFieldLabel = uilabel(app.Step1SelectthedatatoprocessPanel);
            app.DataNameEditFieldLabel.Text = 'DataName';
            app.DataNameEditField = uieditfield(app.Step1SelectthedatatoprocessPanel, 'text');
            app.DataNameEditField.Editable = 'off';
            app.MapsrecognizedListBoxLabel = uilabel(app.Step1SelectthedatatoprocessPanel);
            app.MapsrecognizedListBoxLabel.Text = 'Maps recognized';
            app.MapsrecognizedListBox = uilistbox(app.Step1SelectthedatatoprocessPanel);
            app.MapsrecognizedListBox.Items = {'to be updated'};
            app.MapsrecognizedListBox.Value = 'to be updated';
            app.Step2ROIselectionPanel = uipanel(app.UIFigure);
            app.Step2ROIselectionPanel.Title = 'Step 2 : ROI selection';
            app.UIAxes_ROISelection = uiaxes(app.Step2ROIselectionPanel);
            app.UIAxes_ROISelection.XTick = [];
            app.UIAxes_ROISelection.YTick = [];
            app.ConfirmROIButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.ConfirmROIButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmROIButtonPushed, true);
            app.ConfirmROIButton.Text = 'Confirm ROI ';
            app.SelectROIByDrawingButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.SelectROIByDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIByDrawingButtonPushed, true);
            app.SelectROIByDrawingButton.Text = {'Select ROI'; 'By Drawing'};
            app.CopyROIfromReferenceButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.CopyROIfromReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @CopyROIfromReferenceButtonPushed, true);
            app.CopyROIfromReferenceButton.Text = {'Copy ROI'; 'from Reference'};
            app.RegistertoAtlasButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.RegistertoAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @RegistertoAtlasButtonPushed, true);
            app.RegistertoAtlasButton.Text = 'Register to Atlas';
            app.AutoregisterwhencopyingCheckBox = uicheckbox(app.Step2ROIselectionPanel);
            app.AutoregisterwhencopyingCheckBox.Text = {'Auto-register '; 'when copying'};
            app.SelectROIfromAtlasButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.SelectROIfromAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIfromAtlasButtonPushed, true);
            app.SelectROIfromAtlasButton.Text = 'Select ROI from Atlas';
            app.UpButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.Step2ROIselectionPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.ROIselectoinLabel = uilabel(app.Step2ROIselectionPanel);
            app.ROIselectoinLabel.Text = 'ROI selectoin';
            app.ROIadjustmentandconfirmationLabel = uilabel(app.Step2ROIselectionPanel);
            app.ROIadjustmentandconfirmationLabel.Text = 'ROI adjustment and confirmation';
            app.Methods1ManuallyDrawingLabel = uilabel(app.Step2ROIselectionPanel);
            app.Methods1ManuallyDrawingLabel.Text = {'Methods 1'; 'Manually Drawing'};
            app.Methods2LoadfromAtlasLabel = uilabel(app.Step2ROIselectionPanel);
            app.Methods2LoadfromAtlasLabel.Text = {'Methods 2'; 'Load from Atlas'};
            app.Methods3CopyexistingoneLabel = uilabel(app.Step2ROIselectionPanel);
            app.Methods3CopyexistingoneLabel.Text = {'Methods 3'; 'Copy existing one'};
            app.ROIconfirmedLampLabel = uilabel(app.Step2ROIselectionPanel);
            app.ROIconfirmedLampLabel.Text = 'ROI confirmed';
            app.ROIconfirmedLamp = uilamp(app.Step2ROIselectionPanel);
            app.ROIShiftAmountEditFieldLabel = uilabel(app.Step2ROIselectionPanel);
            app.ROIShiftAmountEditFieldLabel.Text = 'ROI Shift Amount';
            app.ROIShiftAmountEditField = uieditfield(app.Step2ROIselectionPanel, 'numeric');
            app.ROIShiftAmountEditField.Value = 10;
            app.AtlasRegisteredLampLabel = uilabel(app.Step2ROIselectionPanel);
            app.AtlasRegisteredLampLabel.Text = 'Atlas Registered';
            app.AtlasRegisteredLamp = uilamp(app.Step2ROIselectionPanel);
            app.AxialEditFieldLabel_2 = uilabel(app.Step2ROIselectionPanel);
            app.AxialEditFieldLabel_2.Text = 'ULM pixel size in μm:';
            app.AxialEditFieldLabel = uilabel(app.Step2ROIselectionPanel);
            app.AxialEditFieldLabel.Text = 'Axial';
            app.AxialEditField = uieditfield(app.Step2ROIselectionPanel, 'numeric');
            app.AxialEditField.AllowEmpty = 'on';
            app.AxialEditField.Value = [];
            app.LateralEditFieldLabel = uilabel(app.Step2ROIselectionPanel);
            app.LateralEditFieldLabel.Text = 'Lateral';
            app.LateralEditField = uieditfield(app.Step2ROIselectionPanel, 'numeric');
            app.LateralEditField.AllowEmpty = 'on';
            app.LateralEditField.Value = [];
            app.Step3ROIanalysisPanel = uipanel(app.UIFigure);
            app.Step3ROIanalysisPanel.Title = 'Step 3: ROI analysis';
            app.ROISelectionsavedLampLabel = uilabel(app.Step3ROIanalysisPanel);
            app.ROISelectionsavedLampLabel.Text = 'ROI Selection saved';
            app.ROISelectionsavedLamp = uilamp(app.Step3ROIanalysisPanel);
            app.saveButton = uibutton(app.Step3ROIanalysisPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.confirmandanalyzeButton = uibutton(app.Step3ROIanalysisPanel, 'push');
            app.confirmandanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @confirmandanalyzeButtonPushed, true);
            app.confirmandanalyzeButton.Text = 'confirm and analyze';
            app.InputROInameEditFieldLabel = uilabel(app.Step3ROIanalysisPanel);
            app.InputROInameEditFieldLabel.Text = 'Input ROI name';
            app.InputROInameEditField = uieditfield(app.Step3ROIanalysisPanel, 'text');
            app.InputROInameEditField.Value = 'Name the ROI here';
            app.TotalMBcountEditFieldLabel = uilabel(app.Step3ROIanalysisPanel);
            app.TotalMBcountEditFieldLabel.Text = 'Total MB count';
            app.TotalMBcountEditField = uieditfield(app.Step3ROIanalysisPanel, 'numeric');
            app.VesselAreapixelsEditFieldLabel = uilabel(app.Step3ROIanalysisPanel);
            app.VesselAreapixelsEditFieldLabel.Text = 'Vessel Area (pixels)';
            app.VesselAreapixelsEditField = uieditfield(app.Step3ROIanalysisPanel, 'numeric');
            app.MeanMBcountinvesselsEditFieldLabel = uilabel(app.Step3ROIanalysisPanel);
            app.MeanMBcountinvesselsEditFieldLabel.Text = 'Mean MB count in vessels';
            app.MeanMBcountinvesselsEditField = uieditfield(app.Step3ROIanalysisPanel, 'numeric');
            app.SelectedROIprofileLabel = uilabel(app.Step3ROIanalysisPanel);
            app.SelectedROIprofileLabel.Text = 'Selected ROI profile';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIAnalysisGUI_github

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end